classdef GUIprojectct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTScannerUIFigure               matlab.ui.Figure
        AcquisitionPanel                matlab.ui.container.Panel
        ProjectionAngleEditField_2      matlab.ui.control.NumericEditField
        ProjectionAngleEditField_2Label  matlab.ui.control.Label
        ofDetectorsIterationsEditField_2  matlab.ui.control.NumericEditField
        ofDetectorsIterationsEditField_2Label  matlab.ui.control.Label
        SelectionButtonGroup            matlab.ui.container.ButtonGroup
        ReconstructedImageButton        matlab.ui.control.RadioButton
        OriginalImageButton             matlab.ui.control.RadioButton
        RunAcquisitionButton            matlab.ui.control.Button
        SaveReconstructedImageButton    matlab.ui.control.Button
        SelectSampleTypePanel           matlab.ui.container.Panel
        ChooseScannerMethodDropDown     matlab.ui.control.DropDown
        ChooseScannerMethodDropDownLabel  matlab.ui.control.Label
        RunCompareContrastDiffernceButton_2  matlab.ui.control.Button
        SaveSignalIntensityImageButton  matlab.ui.control.Button
        ofDetectorsIterationsEditField  matlab.ui.control.NumericEditField
        ofDetectorsIterationsEditFieldLabel  matlab.ui.control.Label
        ProjectionAngleEditField        matlab.ui.control.NumericEditField
        ProjectionAngleEditFieldLabel   matlab.ui.control.Label
        SIGraphGeneratorPanel           matlab.ui.container.Panel
        SIGraph                         matlab.ui.control.UIAxes
        CTImagePanel                    matlab.ui.container.Panel
        CTImage                         matlab.ui.control.UIAxes
        PhantomImagePanel               matlab.ui.container.Panel
        PhantomImage                    matlab.ui.control.UIAxes
        PhantomPanel                    matlab.ui.container.Panel
        SavePhantomImageButton          matlab.ui.control.Button
        GeneratePhantomDropDown         matlab.ui.control.DropDown
        GeneratePhantomDropDownLabel    matlab.ui.control.Label
        GeneratePhantomButton           matlab.ui.control.Button
        RectangularConfigurationSizePanel  matlab.ui.container.Panel
        CircleRadiiDropDown             matlab.ui.control.DropDown
        CircleRadiiDropDownLabel        matlab.ui.control.Label
        RectangleDimensionsDropDown     matlab.ui.control.DropDown
        RectangleDimensionsDropDownLabel  matlab.ui.control.Label
        VirtualCTScannerLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        shape % Description
        phantom_canvas
        backprojection_canvas
        SI_canvas
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function sg = cRadon(~, img, theta)
                
                step = 1;
                
                angles = 1:step:theta;
                sg = zeros(size(img, 1), length(theta));
                
                for i = 1:length(angles)
                    sg(:, i) = sum(imrotate(img, -angles(i), 'bilinear', 'crop'));
                end

          end

        function ir = cART(~, sg, theta, niter)
            % You can play with steps (deafault 1):
            step = 1;
            
            angles = 1:step:theta;
            [ir, tdiff] = deal(zeros(length(sg)));
            
            for i = 1:niter
                for j = 1:length(angles)
                    ir = imrotate(ir, step, 'bicubic', 'crop');
                    tmp = sum(repelem(sg(:, j), 1, length(ir)), 2);
                    diff = (tmp - sum(ir, 2)) / length(ir);
                    for k = 1:length(ir)
                        tdiff(:, k) = diff;
                    end
                    ir = ir + tdiff;
                end
            end
            
            % Crop final image (Optional)
            % [h, w] = size(sg);
            % ir = imcrop(ir, [(h-w)/2, (h-w)/2, w, w]);
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            size = [256,256];
            app.phantom_canvas = zeros(size);

            cp = [size(1)/2 , size(2)/2];
            phantom_selection = app.GeneratePhantomDropDown.Value;

            if(strcmp(phantom_selection, 'Circle Phantom'))
                radius_value = app.CircleRadiiDropDown.Value;

                E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                      -0.8, 0.8999, 0.8999, 0, 0, 0;
                      0.8, 0.2, 0.2, 0, 0, 0
                      -0.2, 0.06, 0.06, -0.11, 0.5, 0;
                      0.2, 0.05, 0.05, -0.11, 0.5, 0;
                      -0.2, 0.12, 0.12, -0.60, -0.1, 0;
                      0.2, 0.11, 0.11, -0.60, -0.1, 0;
                      -0.2, 0.17, 0.17, 0, -0.55, 0;
                      0.2, 0.16, 0.16, 0, -0.55, 0;
                ];
                
                if strcmpi(radius_value, '20%')
                    E1 = [1.0, 0.9, 0.9, 0, 0, 0;
                          -0.8, 0.8999, 0.8999, 0, 0, 0;
                          0.8, 0.2, 0.2, 0, 0, 0
                          -0.2, 0.06 * 1.2, 0.06 * 1.2, -0.11, 0.5, 0;
                          0.2, 0.05 * 1.2, 0.05 * 1.2, -0.11, 0.5, 0;
                          -0.2, 0.12 * 1.2, 0.12 * 1.2, -0.60, -0.1, 0;
                          0.2, 0.11 * 1.2, 0.11 * 1.2, -0.60, -0.1, 0;
                          -0.2, 0.17 * 1.2, 0.17 * 1.2, 0, -0.55, 0;
                          0.2, 0.16 * 1.2, 0.16 * 1.2, 0, -0.55, 0;
                    ];
                end

                [~, A2] = phantom(E1, 256);
                P1 = phantom(A2, 256);
                app.phantom_canvas = P1;
        
            elseif(strcmp(phantom_selection, 'Rectangular Phantom'))
                width = 0;%5*app.WidthSlider.Value;
                length = 0;%5*app.LengthSlider_2.Value;
                
                %rectangle dimension drop-down options
                dimension_value = app.RectangleDimensionsDropDown.Value;

                if strcmp(dimension_value, '60x30')
                    length = 60;
                    width = 30;
                elseif strcmp(dimension_value, '45x45')
                    length = 45;
                    width = 45;
                elseif strcmp(dimension_value, '30x60')
                    length = 30;
                    width = 60;
                end

                r_size = floor([width*2 length*2]);

                % get user input - rectangular size 
                % r_para[width, height]
                r_para = r_size;
        
                % x-axis = height
                % height x-axis starting point
                x1 = floor(cp(2) - r_para(2)/2);
                % height x-axis ending point
                x2 = x1 + r_para(2);
                
                % y-axis = width
                % width y-axix starting point
                y1 = floor(cp(1) - r_para(1)/2);
                % width y-axix ending point
                y2 = y1 + r_para(1);
        
                % draw the squre
                app.phantom_canvas(x1 : x2, y1 : y2) = 256;
                    
            else
                app.phantom_canvas = phantom(256);
            end

            imwrite(app.phantom_canvas,'./test.png');
            imshow(app.phantom_canvas,'parent', app.PhantomImage);
            
        end

        % Button pushed function: SavePhantomImageButton
        function SavePhantomImageButtonPushed(app, event)
            if (isempty(app.phantom_canvas))
                errordlg('Phantom not found. Please generate a phantom','Phantom Error')
            end

            imwrite(app.phantom_canvas,'./generate-phantom-test.png');
        end

        % Button pushed function: RunCompareContrastDiffernceButton_2
        function RunCompareContrastDiffernceButton_2Pushed(app, event)
            method_selection = app.ChooseScannerMethodDropDown.Value;

            if strcmp(method_selection, 'Option 1')
                app.phantom_canvas = phantom(256);
            end

            imshow(app.phantom_canvas, 'Parent', app.CTImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTScannerUIFigure = uifigure('Visible', 'off');
            app.CTScannerUIFigure.Name = 'Virtual CT Scanner';
            app.VirtualCTScannerLabel = uilabel(app.CTScannerUIFigure);
            app.VirtualCTScannerLabel.Text = 'Virtual CT Scanner';
            app.PhantomPanel = uipanel(app.CTScannerUIFigure);
            app.PhantomPanel.Title = 'Phantom';
            app.RectangularConfigurationSizePanel = uipanel(app.PhantomPanel);
            app.RectangularConfigurationSizePanel.Title = 'Rectangular Configuration Size';
            app.RectangleDimensionsDropDownLabel = uilabel(app.RectangularConfigurationSizePanel);
            app.RectangleDimensionsDropDownLabel.Text = 'Rectangle Dimensions';
            app.RectangleDimensionsDropDown = uidropdown(app.RectangularConfigurationSizePanel);
            app.RectangleDimensionsDropDown.Items = {'30x60', '45x45', '60x30'};
            app.RectangleDimensionsDropDown.Value = '60x30';
            app.CircleRadiiDropDownLabel = uilabel(app.RectangularConfigurationSizePanel);
            app.CircleRadiiDropDownLabel.Text = 'Circle Radii';
            app.CircleRadiiDropDown = uidropdown(app.RectangularConfigurationSizePanel);
            app.CircleRadiiDropDown.Items = {'0%', '20%'};
            app.CircleRadiiDropDown.Value = '0%';
            app.GeneratePhantomButton = uibutton(app.PhantomPanel, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.GeneratePhantomDropDownLabel = uilabel(app.PhantomPanel);
            app.GeneratePhantomDropDownLabel.Text = 'Generate Phantom';
            app.GeneratePhantomDropDown = uidropdown(app.PhantomPanel);
            app.GeneratePhantomDropDown.Items = {'Circle Phantom', 'Rectangular Phantom', 'Head Phantom'};
            app.GeneratePhantomDropDown.Value = 'Circle Phantom';
            app.SavePhantomImageButton = uibutton(app.PhantomPanel, 'push');
            app.SavePhantomImageButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhantomImageButtonPushed, true);
            app.SavePhantomImageButton.Text = 'Save Phantom Image';
            app.PhantomImagePanel = uipanel(app.CTScannerUIFigure);
            app.PhantomImagePanel.Title = 'Phantom Image';
            app.PhantomImage = uiaxes(app.PhantomImagePanel);
            zlabel(app.PhantomImage, 'Z')
            app.CTImagePanel = uipanel(app.CTScannerUIFigure);
            app.CTImagePanel.Title = 'Acquired CT Signal Intensity Graph';
            app.CTImage = uiaxes(app.CTImagePanel);
            zlabel(app.CTImage, 'Z')
            app.SIGraphGeneratorPanel = uipanel(app.CTScannerUIFigure);
            app.SIGraphGeneratorPanel.Title = 'SI Graph Generator';
            app.SIGraph = uiaxes(app.SIGraphGeneratorPanel);
            zlabel(app.SIGraph, 'Z')
            app.SelectSampleTypePanel = uipanel(app.CTScannerUIFigure);
            app.SelectSampleTypePanel.Title = 'Select Sample Type';
            app.ProjectionAngleEditFieldLabel = uilabel(app.SelectSampleTypePanel);
            app.ProjectionAngleEditFieldLabel.Text = 'Projection Angle';
            app.ProjectionAngleEditField = uieditfield(app.SelectSampleTypePanel, 'numeric');
            app.ofDetectorsIterationsEditFieldLabel = uilabel(app.SelectSampleTypePanel);
            app.ofDetectorsIterationsEditFieldLabel.Text = '# of Detectors (Iterations)';
            app.ofDetectorsIterationsEditField = uieditfield(app.SelectSampleTypePanel, 'numeric');
            app.SaveSignalIntensityImageButton = uibutton(app.SelectSampleTypePanel, 'push');
            app.SaveSignalIntensityImageButton.Text = 'Save Signal Intensity Image';
            app.RunCompareContrastDiffernceButton_2 = uibutton(app.SelectSampleTypePanel, 'push');
            app.RunCompareContrastDiffernceButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunCompareContrastDiffernceButton_2Pushed, true);
            app.RunCompareContrastDiffernceButton_2.Text = 'Run Compare Contrast Differnce';
            app.ChooseScannerMethodDropDownLabel = uilabel(app.SelectSampleTypePanel);
            app.ChooseScannerMethodDropDownLabel.Text = 'Choose Scanner Method';
            app.ChooseScannerMethodDropDown = uidropdown(app.SelectSampleTypePanel);
            app.AcquisitionPanel = uipanel(app.CTScannerUIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.SaveReconstructedImageButton = uibutton(app.AcquisitionPanel, 'push');
            app.SaveReconstructedImageButton.Text = 'Save Reconstructed Image';
            app.RunAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.RunAcquisitionButton.Text = 'Run Acquisition';
            app.SelectionButtonGroup = uibuttongroup(app.AcquisitionPanel);
            app.SelectionButtonGroup.Title = 'Selection';
            app.OriginalImageButton = uiradiobutton(app.SelectionButtonGroup);
            app.OriginalImageButton.Text = 'Original Image';
            app.OriginalImageButton.Value = true;
            app.ReconstructedImageButton = uiradiobutton(app.SelectionButtonGroup);
            app.ReconstructedImageButton.Text = 'Reconstructed Image';
            app.ofDetectorsIterationsEditField_2Label = uilabel(app.AcquisitionPanel);
            app.ofDetectorsIterationsEditField_2Label.Text = '# of Detectors (Iterations)';
            app.ofDetectorsIterationsEditField_2 = uieditfield(app.AcquisitionPanel, 'numeric');
            app.ProjectionAngleEditField_2Label = uilabel(app.AcquisitionPanel);
            app.ProjectionAngleEditField_2Label.Text = 'Projection Angle';
            app.ProjectionAngleEditField_2 = uieditfield(app.AcquisitionPanel, 'numeric');
            % Show the figure after all components are created
            app.CTScannerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIprojectct

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTScannerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTScannerUIFigure)
        end
    end
end